classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultLabel                     matlab.ui.control.Label
        RegressionApp                   matlab.ui.control.Label
        ResearchEditField               matlab.ui.control.NumericEditField
        Ingresatuexperienciaeninvestigacin01EditFieldLabel  matlab.ui.control.Label
        GPAEditField                    matlab.ui.control.NumericEditField
        IngresatuGPA010EditFieldLabel   matlab.ui.control.Label
        LOREditField                    matlab.ui.control.NumericEditField
        IngresatuLOR05EditFieldLabel    matlab.ui.control.Label
        SOPEditField                    matlab.ui.control.NumericEditField
        IngresatuSOP05EditFieldLabel    matlab.ui.control.Label
        SchoolRatingEditField           matlab.ui.control.NumericEditField
        Ingresatucalificacinuniversitaria05EditFieldLabel  matlab.ui.control.Label
        TOEFLEEditField                 matlab.ui.control.NumericEditField
        IngresatupuntuacinTOEFL0120EditFieldLabel  matlab.ui.control.Label
        GREEEditField                   matlab.ui.control.NumericEditField
        IngresatuGRE0340EditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            datos = readmatrix("Admission_Predict.csv");
            n = length(datos);
            
            % declaración de las sumatorias que se utilizarán para la regresión lineal
            % x1=GRE; x2=TOEFL; x3=SchoolRating; x4=SOP; x5=LOR; x6=CGPA; x7=Research;
            % y=Chance
            sum_x1 = 0;
            sum_x2 = 0;
            sum_x3= 0;
            sum_x4 = 0;
            sum_x5 = 0;
            sum_x6= 0;
            sum_x7= 0;
            sum_y = 0;
            
            % combinaciones con las demás variables
            % para x1
            sum_x1x1 = 0;
            sum_x1x2 = 0;
            sum_x1x3 = 0;
            sum_x1x4 = 0;
            sum_x1x5 = 0;
            sum_x1x6 = 0;
            sum_x1x7 = 0;
            sum_x1y = 0;
            
            % para x2
            % sum_x2x1 = 0;
            sum_x2x2 = 0;
            sum_x2x3 = 0;
            sum_x2x4 = 0;
            sum_x2x5 = 0;
            sum_x2x6 = 0;
            sum_x2x7 = 0;
            sum_x2y = 0;
            
            % para x3
            % sum_x3x1 = 0;
            % sum_x3x2 = 0;
            sum_x3x3 = 0;
            sum_x3x4 = 0;
            sum_x3x5 = 0;
            sum_x3x6 = 0;
            sum_x3x7 = 0;
            sum_x3y = 0;
            
            % para x4
            % sum_x4x1 = 0;
            % sum_x4x2 = 0;
            % sum_x4x3 = 0;
            sum_x4x4 = 0;
            sum_x4x5 = 0;
            sum_x4x6 = 0;
            sum_x4x7 = 0;
            sum_x4y = 0;
            
            % para x5
            % sum_x5x1 = 0;
            % sum_x5x2 = 0;
            % sum_x5x3 = 0;
            % sum_x5x4 = 0;
            sum_x5x5 = 0;
            sum_x5x6 = 0;
            sum_x5x7 = 0;
            sum_x5y = 0;
            
            % para x6
            % sum_x6x1 = 0;
            % sum_x6x2 = 0;
            % sum_x6x3 = 0;
            % sum_x6x4 = 0;
            % sum_x6x5 = 0;
            sum_x6x6 = 0;
            sum_x6x7 = 0;
            sum_x6y = 0;
            
            % para x7
            % sum_x7x1 = 0;
            % sum_x7x2 = 0;
            % sum_x7x3 = 0;
            % sum_x7x4 = 0;
            % sum_x7x5 = 0;
            % sum_x7x6 = 0;
            sum_x7x7 = 0;
            sum_x7y = 0;
            % Las variables que están comentadas son aquellas que se son equivalentes a
            % variables que ya se definieron anteriormente, por ejemplo x1*x2 = x2*x1

            % calculo de sumatorias
            % i = 2:n porque i=1 son los títulos
            for i = 2:n
                % para x1 y sus combinaciones
                sum_x1 = datos(i,2) + sum_x1;
                sum_x1x1 = datos(i,2) * datos(i,2) + sum_x1x1;
                sum_x1x2 = datos(i,2) * datos(i,3) + sum_x1x2;
                sum_x1x3 = datos(i,2) * datos(i,4) + sum_x1x3;
                sum_x1x4 = datos(i,2) * datos(i,5) + sum_x1x4;
                sum_x1x5 = datos(i,2) * datos(i,6) + sum_x1x5;
                sum_x1x6 = datos(i,2) * datos(i,7) + sum_x1x6;
                sum_x1x7 = datos(i,2) * datos(i,8) + sum_x1x7;
                sum_x1y = datos(i,2) * datos(i,9) + sum_x1y;
                
                % para x2 y sus combinaciones
                sum_x2 = datos(i,3) + sum_x2;
                sum_x2x2 = datos(i,3) * datos(i,3) + sum_x2x2;
                sum_x2x3 = datos(i,3) * datos(i,4) + sum_x2x3;
                sum_x2x4 = datos(i,3) * datos(i,5) + sum_x2x4;
                sum_x2x5 = datos(i,3) * datos(i,6) + sum_x2x5;
                sum_x2x6 = datos(i,3) * datos(i,7) + sum_x2x6;
                sum_x2x7 = datos(i,3) * datos(i,8) + sum_x2x7;
                sum_x2y = datos(i,3) * datos(i,9) + sum_x2y;
            
                % para x3 y sus combinaciones
                sum_x3 = datos(i,4) + sum_x3;
                sum_x3x3 = datos(i,4) * datos(i,4) + sum_x3x3;
                sum_x3x4 = datos(i,4) * datos(i,5) + sum_x3x4;
                sum_x3x5 = datos(i,4) * datos(i,6) + sum_x3x5;
                sum_x3x6 = datos(i,4) * datos(i,7) + sum_x3x6;
                sum_x3x7 = datos(i,4) * datos(i,8) + sum_x3x7;
                sum_x3y = datos(i,4) * datos(i,9) + sum_x3y;
            
                % para x4 y sus combinaciones
                sum_x4 = datos(i,5) + sum_x4;
                sum_x4x4 = datos(i,5) * datos(i,5) + sum_x4x4;
                sum_x4x5 = datos(i,5) * datos(i,6) + sum_x4x5;
                sum_x4x6 = datos(i,5) * datos(i,7) + sum_x4x6;
                sum_x4x7 = datos(i,5) * datos(i,8) + sum_x4x7;
                sum_x4y = datos(i,5) * datos(i,9) + sum_x4y;
            
                % para x5 y sus combinaciones
                sum_x5 = datos(i,6) + sum_x5;
                sum_x5x5 = datos(i,6) * datos(i,6) + sum_x5x5;
                sum_x5x6 = datos(i,6) * datos(i,7) + sum_x5x6;
                sum_x5x7 = datos(i,6) * datos(i,8) + sum_x5x7;
                sum_x5y = datos(i,6) * datos(i,9) + sum_x5y;
            
                % para x6 y sus combinaciones
                sum_x6 = datos(i,7) + sum_x6;
                sum_x6x6 = datos(i,7) * datos(i,7) + sum_x6x6;
                sum_x6x7 = datos(i,7) * datos(i,8) + sum_x6x7;
                sum_x6y = datos(i,7) * datos(i,9) + sum_x6y;
            
                % para x7 y sus combinaciones
                sum_x7 = datos(i,8) + sum_x7;
                sum_x7x7 = datos(i,8) * datos(i,8) + sum_x7x7;
                sum_x7y = datos(i,8) * datos(i,9) + sum_x7y;
            
                % para y
                sum_y = datos(i,9) + sum_y;
            end

            % generación de matriz de coeficientes A
            A = [sum_x1 sum_x2 sum_x3 sum_x4 sum_x5 sum_x6 sum_x7 n;
                 sum_x1x1 sum_x1x2 sum_x1x3 sum_x1x4 sum_x1x5 sum_x1x6 sum_x1x7 sum_x1;
                 sum_x1x2 sum_x2x2 sum_x2x3 sum_x2x4 sum_x2x5 sum_x2x6 sum_x2x7 sum_x2;
                 sum_x1x3 sum_x2x3 sum_x3x3 sum_x3x4 sum_x3x5 sum_x3x6 sum_x3x7 sum_x3;
                 sum_x1x4 sum_x2x4 sum_x3x4 sum_x4x4 sum_x4x5 sum_x4x6 sum_x4x7 sum_x4;
                 sum_x1x5 sum_x2x5 sum_x3x5 sum_x4x5 sum_x5x5 sum_x5x6 sum_x5x7 sum_x5;
                 sum_x1x6 sum_x2x6 sum_x3x6 sum_x4x6 sum_x5x6 sum_x6x6 sum_x6x7 sum_x6;
                 sum_x1x7 sum_x2x7 sum_x3x7 sum_x4x7 sum_x5x7 sum_x6x7 sum_x7x7 sum_x7];
            
            % generación de matriz de términos independientes B
            B = [sum_y; sum_x1y; sum_x2y; sum_x3y; sum_x4y; sum_x5y; sum_x6y; sum_x7y];
            
            % obtención del vector de incógnitas x
            x = A\B; % notación de matlab para inv(A)*B
            a1=x(1,1); a2=x(2,1); a3=x(3,1); a4=x(4,1); a5=x(5,1); a6=x(6,1); a7=x(7,1); a8=x(8,1);
            
            % Obtención de los valores ingresados por el usuario
            x1 = app.GREEEditField.Value;
            x2 = app.TOEFLEEditField.Value;
            x3 = app.SchoolRatingEditField.Value;
            x4 = app.SOPEditField.Value;
            x5 = app.LOREditField.Value;
            x6 = app.GPAEditField.Value;
            x7 = app.ResearchEditField.Value;
            
            % Cálculo de la predicción
            y = a1*x1 + a2*x2 + a3*x3 + a4*x4 +a5*x5 + a6*x6 + a7*x7 +a8;            
            
            % Mostrar el resultado en la etiqueta ResultLabel
            app.ResultLabel.Text = "Tu posibilidad de ingreso a la maestría es " + num2str(y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'cool');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'starwatch.png');
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Resultado';
            app.IngresatuGRE0340EditFieldLabel = uilabel(app.UIFigure);
            app.IngresatuGRE0340EditFieldLabel.Text = 'Ingresa tu GRE (0-340):';
            app.GREEEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresatupuntuacinTOEFL0120EditFieldLabel = uilabel(app.UIFigure);
            app.IngresatupuntuacinTOEFL0120EditFieldLabel.Text = 'Ingresa tu puntuación TOEFL (0-120):';
            app.TOEFLEEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ingresatucalificacinuniversitaria05EditFieldLabel = uilabel(app.UIFigure);
            app.Ingresatucalificacinuniversitaria05EditFieldLabel.Text = 'Ingresa tu calificación universitaria (0-5):';
            app.SchoolRatingEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresatuSOP05EditFieldLabel = uilabel(app.UIFigure);
            app.IngresatuSOP05EditFieldLabel.Text = 'Ingresa tu SOP (0-5):';
            app.SOPEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresatuLOR05EditFieldLabel = uilabel(app.UIFigure);
            app.IngresatuLOR05EditFieldLabel.Text = 'Ingresa tu LOR (0-5):';
            app.LOREditField = uieditfield(app.UIFigure, 'numeric');
            app.IngresatuGPA010EditFieldLabel = uilabel(app.UIFigure);
            app.IngresatuGPA010EditFieldLabel.Text = 'Ingresa tu GPA (0-10):';
            app.GPAEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ingresatuexperienciaeninvestigacin01EditFieldLabel = uilabel(app.UIFigure);
            app.Ingresatuexperienciaeninvestigacin01EditFieldLabel.Text = 'Ingresa tu experiencia en investigación (0-1):';
            app.ResearchEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegressionApp = uilabel(app.UIFigure);
            app.RegressionApp.Text = 'Aplicación de regresión';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end