classdef inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AlanCarrascoJonathanGarcaLabel  matlab.ui.control.Label
        EstaaplicacinderegresinlinealfuerealizadaporLabel  matlab.ui.control.Label
        BienvenidosLabel                matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Crear la figura del cuadro emergente (callout)
            fig = uifigure('Name', 'Abrir Segunda Aplicación');
            
            % Crear el cuadro emergente (callout)
            callout = uicallout(fig, 'Position', [200 200 300 100], 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            
            % Configurar el texto del cuadro emergente
            callout.Text = '¿Deseas abrir la segunda aplicación?';
            
            % Crear los botones de acción del cuadro emergente
            abrirButton = uibutton(fig, 'Text', 'Abrir', 'Position', [110 30 80 22], 'ButtonPushedFcn', @(~,~) abrirSegundaApp(app));
            cancelarButton = uibutton(fig, 'Text', 'Cancelar', 'Position', [210 30 80 22], 'ButtonPushedFcn', @(~,~) close(fig));
            
            % Mostrar la figura del cuadro emergente
            fig.Visible = 'on';
        end
        
        function abrirSegundaApp(app)
            % Cerrar la primera aplicación
            app.hide();
            
            % Abrir la segunda aplicación
            open('app1.mlapp');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'starwars.jpg';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start (:';
            app.BienvenidosLabel = uilabel(app.UIFigure);
            app.BienvenidosLabel.Text = '¡Bienvenidos!';
            app.EstaaplicacinderegresinlinealfuerealizadaporLabel = uilabel(app.UIFigure);
            app.EstaaplicacinderegresinlinealfuerealizadaporLabel.Text = 'Esta aplicación de regresión lineal fue realizada por:';
            app.AlanCarrascoJonathanGarcaLabel = uilabel(app.UIFigure);
            app.AlanCarrascoJonathanGarcaLabel.Text = {'Alan Carrasco'; 'Jonathan García'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end